classdef DataCrossing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AuthenticationTab               matlab.ui.container.Tab
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        SCCpasswordEditField            matlab.ui.control.EditField
        SCCpasswordEditFieldLabel       matlab.ui.control.Label
        SCCuserEditField                matlab.ui.control.EditField
        SCCuserEditFieldLabel           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PersonalSCCAuthenticationLabel  matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        GenericSCCAuthenticationLabel   matlab.ui.control.Label
        PreBuildingTab                  matlab.ui.container.Tab
        CurrentIDEditField              matlab.ui.control.EditField
        CurrentIDEditFieldLabel         matlab.ui.control.Label
        HeightmaxSpinner                matlab.ui.control.Spinner
        HeightmaxSpinnerLabel           matlab.ui.control.Label
        HeightminSpinner                matlab.ui.control.Spinner
        HeightminSpinnerLabel           matlab.ui.control.Label
        StopdateDatePicker              matlab.ui.control.DatePicker
        StopdateDatePickerLabel         matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        AltiudeSwitch                   matlab.ui.control.Switch
        ExportLIDARCheckBox             matlab.ui.control.CheckBox
        RButton_2                       matlab.ui.control.Button
        RButton                         matlab.ui.control.Button
        ViewfiguresCheckBox             matlab.ui.control.CheckBox
        StopdateDatePickerLabel_5       matlab.ui.control.Label
        PlotdataButton                  matlab.ui.control.Button
        GRASPfilebuilderLabel           matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        SettingsButtonGroup             matlab.ui.container.ButtonGroup
        D1P_L_VDPhotom_pollidar_depButton  matlab.ui.control.RadioButton
        D1_L_VDPhotometerlidar_depButton  matlab.ui.control.RadioButton
        D1P_LPhotom_pollidarButton      matlab.ui.control.RadioButton
        D1_LPhotometerlidarButton       matlab.ui.control.RadioButton
        StopdateDatePickerLabel_2       matlab.ui.control.Label
        LidardatacollectionLabel        matlab.ui.control.Label
        FilterbydatesLabel              matlab.ui.control.Label
        AcceptButton                    matlab.ui.control.Button
        SelectedIDLabelDropDown         matlab.ui.control.DropDown
        FilterdatesButton               matlab.ui.control.Button
        LidardatabaseloadLabel          matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        PlotfigureButton                matlab.ui.control.Button
        SelectafiguretoshowDropDown     matlab.ui.control.DropDown
        SelectthefiguretoshowLabel      matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SelectameasurementfiletoshowDropDown_2  matlab.ui.control.DropDown
        SelectameasurementfiletoshowDropDown_2Label  matlab.ui.control.Label
        SelectalidermeasurementIDDropDown  matlab.ui.control.DropDown
        SelectalidermeasurementIDDropDownLabel  matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        SavefiguresButton               matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        UserGuidesNode                  matlab.ui.container.TreeNode
        FunctionsNode                   matlab.ui.container.TreeNode
        HTML                            matlab.ui.control.HTML
        LIDARButton                     matlab.ui.control.Button
        StopdateDatePickerLabel_7       matlab.ui.control.Label
        StopdateDatePickerLabel_6       matlab.ui.control.Label
        OutputButton                    matlab.ui.control.Button
        AeronetButton                   matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        DataCrossingLabel               matlab.ui.control.Label
        StopdateDatePickerLabel_4       matlab.ui.control.Label
        DataBaseTextArea                matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        RealSCCPassword = '';  % Variable para almacenar la contraseña real
        RealPassword = '';

        Auth;
        EncAuth;
                
        measureIDFolder = "";

        result={}; %Creamos una instancia de la variable result
        sendRequestExecuted = false; 
        selected_measure_ID;


        idType ='';

        logger = [];
        errorMessage
        errorId

%       dataStruct
        dataStruct = struct([]);

        system_ids = [];

        
        foundFilesELDA = 0;
        foundFilesAOD = 0;
        foundFilesAlm = 0; 
        foundFilesAlp = 0;
        foundFilesAll = 0;

        heightELPPLimitMin = 0;
        heightELPPLimitMax = 0;
        heightVDLimitMin = 0;
        heightVDLimitMax = 0;


        %Se inicializa la variable de envío de información con el botón a FALSE,
        % ya que no hay envío de información
    end
    events
        ResultChanged
    end


%             addlistener(app, 'ResultChanged', @app.handleResultChanged)
%             app.SendrequestButton.SendrequestButtonPushed = @app.SendrequestButtonPushed;
% %Se configura el handle o seguimiento del cambio de resultado para
% %actualizar el resultado si se realizan cambios
% 
%             folderPath = './database'; % Cambia esto por la ruta de tu carpeta
%             fileList = dir(folderPath);
%             
%             % Mostrar los nombres de todos los archivos y carpetas
%             for k = 1:length(fileList)
%                 disp(fileList(k).name);
%             end
% 
% 
%         end
% 
%         function handleResultChanged(app,~, ~)
%             assignin('base', 'result', app.result);
%             disp('Result Changed');
%             disp(app.result);
%             %Se monitoriza la variable result y los cambios que esta sufre
%             
%        end
        

%     end
    
    methods (Access = private)
        
        function [foundFilesAOD, foundFilesAlm, foundFilesAlp, foundFilesAll] = loadResources(app)

            run('config_file.m');
            
            tWorkSpaceNames = table('Size', [0, 3], ...
                'VariableTypes', {'char', 'char', 'char'}, ...
                'VariableNames', {'table_name', 'type', 'location'});
    
            assignin('base', 'WSTNames' , tWorkSpaceNames)

            currentFolder = fileparts(mfilename('fullpath'));
            URL_aeronet = fullfile(currentFolder, CONFIG_AERONET_folder);

            logMessage(app, 'RESOURCES: reading ''AOD'' files')
            foundFilesAOD = read_Aeronet( URL_aeronet );

            pause(0.25);
            logMessage(app, 'RESOURCES: reading ''ALM'' files');
            foundFilesAlm = read_Alm( URL_aeronet );

            pause(0.25);
            logMessage(app, 'RESOURCES: reading ''ALL'' files');
            foundFilesAll = read_All( URL_aeronet );

            pause(0.25);
            logMessage(app, 'RESOURCES: reading ''ALP'' files');
            foundFilesAlp = read_Alp( URL_aeronet );

        end
        
        %% WRITE A LOG MESSAGE
        function logMessage(app, message)
        %%! logMessage(app, message)
        %%! Descripción
        %!
        %! Imprime un mensaje de texto en el contendedor de logs
        %! La función añade automáticamente la fecha de impresión de log
        %! (cuando se generó el evento) y la hora
        %!
        %! *PARÁMETROS DE ENTRADA*
        %!
        %! * |app: [App object]| -
        %!      Instancia de la clase de la aplicación que estás creando
        %!
        %! * |message: [string]| -
        %!      Mensaje de texto para imprimir en el log
        %!
        %! *VALORES DE SALIDA*
        %!
        %! * No devuelve ningún valor
        %! 
            currentDateTime = datetime('now');
            currentDateString = datestr(currentDateTime , 'dd-mm-yyyy HH:MM:SS');
            app.logger = [currentDateString, '> ', message, newline, app.logger];
            app.DataBaseTextArea.Value = app.logger;      
        end
        
       %% For Plotting Tab
       function reloadMeasuresID(app)
        %%! measuresIDfound = reloadMeasuresID(app)
        %%! DESCRIPTION
        %!
        %! Actualiza las medidas disponibles MeasureID
        %! adecuada para poder ser utilizados en las funciones de cálculo y ploteado:
        %!
        %! *PARÁMETROS DE ENTRADA*
        %!
        %! * |app: [App object]| -
        %!      Instancia de la clase de la aplicación que estás creando
        %!
        %! *VALORES DE SALIDA*
        %!
        %! * No devuelve ningún valor
        %! 


            measuresIDfound = 0;
            logMessage(app, 'Loading Measures ID');
            
            if isfolder(app.measureIDFolder)
                measureIDFiles = dir(app.measureIDFolder);
                measureIDDirFlags = [measureIDFiles.isdir];
                measureIDSubFolders = measureIDFiles(measureIDDirFlags);
                measureIDSubFolders = measureIDSubFolders(~ismember({measureIDSubFolders.name}, {'.', '..'}));
            
                disp('Subcarpetas en la carpeta measureID:');
                logMessage(app, ['Measure ID found: ', num2str(length(measureIDSubFolders))]);
                for k = 1:length(measureIDSubFolders)
                    app.SelectalidermeasurementIDDropDown.Items = {measureIDSubFolders.name};
                    logMessage(app, ['Measure ID: ', measureIDSubFolders(k).name]);
                end

                reloadFigureFiles(app);
            else
                disp(['La carpeta ', app.measureIDFolder, ' no existe.']);
                logMessage(app, ['La carpeta ', app.measureIDFolder, ' no existe.']);
            end
        end


        function results = plotter(app)

            altMin = app.HeightminSpinner.Value;
            altMax = app.HeightmaxSpinner.Value;
            VDmagFactor = 10^11;
            TPCmagFactor = 10000;
            aLegends = {};

            VDhasNaNMessage = [];
            VDhasNaNCount = 0;
            TPChasNaNMessage = [];
            TPChasNaNCount = 0;

            [tfElda, cont_elda] = getFilteredTable('elda', 1);
            
            app.UIAxes.reset;
            cla(app.UIAxes);

            %% Extracting ELDA VD info
            vdLineColor = 'black';
            vdCount = 0;
            for k=1:height(tfElda)
                currentVD = cell2mat(tfElda(k,:).volumedepolarization);
                if isnan(currentVD)
                    disp(['No hay datos de depolización en la longitud de onda ', num2str(tfElda(k,:).wavelength)]);
                else
                    
                    if(vdCount > 0) 
                        vdLineColor = 'magenta';
                    end

                    cVDEldaAltitude = cell2mat(tfElda(k,:).elda_altitude);
                    cVDEldaValue = cell2mat(tfElda(k,:).volumedepolarization);
            
                    idx = find(cVDEldaAltitude > altMin & cVDEldaAltitude < altMax);
                    cVDEldaAltitude = cVDEldaAltitude(idx);
                    cVDEldaValue = cVDEldaValue(idx);
                    plot(app.UIAxes, cVDEldaValue * VDmagFactor, cVDEldaAltitude, 'Color', vdLineColor), hold(app.UIAxes, 'on');
                    aLegends = [aLegends, ['VD', num2str(tfElda(k,:).wavelength)]]; 

                    % Detectar NaN en los valores VD
                    VDhasNaN = any(isnan(cVDEldaValue), 'all');
                    if VDhasNaN
                        VDhasNaNCount = VDhasNaNCount +1;
                        VDhasNaNMessage = [VDhasNaNMessage, [num2str(tfElda(k,:).wavelength), ' ']];
                    end
                    vdCount = vdCount+1;
                end
            end


            %% Extracting ELPP info (total_power_channel)
            for k=1:height(tfElda)
                currentTPC = cell2mat(tfElda(k,:).total_power_channel);
                if isnan(currentTPC)
                    disp(['No hay datos del Total Power Channel en la longitud de onda ', num2str(tfElda(k,:).wavelength)]);
                else
                    cTPCElppAltitude = cell2mat(tfElda(k,:).elpp_altitude);
                    cTPCElppValue = cell2mat(tfElda(k,:).total_power_channel);
                   
                    idx = find(cTPCElppAltitude > altMin & cTPCElppAltitude < altMax);
                    cTPCElppAltitude = cTPCElppAltitude(idx);
                    cTPCElppValue = cTPCElppValue(idx);
                    
                    %% Amplifica Total Power Channel para la longitud de onda 1064
                    if tfElda(k,:).wavelength == 1064
                        cTPCElppValue = cTPCElppValue * TPCmagFactor;
                    end
            
                    plot(app.UIAxes, cTPCElppValue, cTPCElppAltitude, 'Color', tfElda.attr_color{k}), hold(app.UIAxes, 'on');
                    aLegends = [aLegends, ['TPC', num2str(tfElda(k,:).wavelength)]]; 

                    % Detectar NaN en los valores VD
                    TPChasNaN = any(isnan(cTPCElppValue), 'all');
                    if TPChasNaN
                        TPChasNaNCount = TPChasNaNCount +1;
                        TPChasNaNMessage = [TPChasNaNMessage, [num2str(tfElda(k,:).wavelength), ' ']];
                    end
                end
            end
            
            ylabel(app.UIAxes, 'Altitude');
            xlabel(app.UIAxes, 'Range corrected signal');

            disp(aLegends);
            legend(app.UIAxes, aLegends);
            grid (app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            pause(2);
            drawnow limitrate;

            if VDhasNaNCount > 0
                VDhasNaNMessage = ['Selected range between ', num2str(altMin), ' and ', num2str(altMax), ' contains at least one NaN value in the Volume Depolization vector for the wavelength(s) ', VDhasNaNMessage];
                errordlg(VDhasNaNMessage, 'Invalid values in data (NaN)');
            end
            
            if TPChasNaNCount > 0
                TPChasNaNMessage = ['Selected range between ', num2str(altMin), ' and ', num2str(altMax), ' contains at least one NaN value in the Total Power Channel vector for the wavelength(s) ', TPChasNaNMessage];
                errordlg(TPChasNaNMessage, 'Invalid values in data (NaN)');
            end
            
            disp(VDhasNaNMessage);
            disp(TPChasNaNMessage);

            disp(TPChasNaNCount);
            disp(VDhasNaNCount);

            % minAltitudeCandidates = [min(cell2mat( tfElda(1,:).elpp_altitude))];
            % maxAltitudeCandidates = [max(cell2mat( tfElda(1,:).elpp_altitude))];
            % 
            % for k=1 : height(tfElda)
            %     VolDep = tfElda.volumedepolarization{k};
            %     if isnan(VolDep)
            %         disp('Ignoring elda without Volume Depolarizaton')
            %     else
            %         numericArray = cell2mat(cellfun(@(x) x(:), tfElda.elda_altitude(k,1), 'UniformOutput', false));
            % 
            %         minAltitudeCandidates = [minAltitudeCandidates, min(numericArray(:))];
            %         maxAltitudeCandidates = [maxAltitudeCandidates, max(numericArray(:))];
            % 
            %         disp(['Min. height for VD ', num2str(tfElda.wavelength(k,1)), ' is ', num2str(min(numericArray(:)))]);
            %         disp(['Max. height for VD ', num2str(tfElda.wavelength(k,1)), ' is ', num2str(max(numericArray(:)))]);     
            %     end
            % end
            % 
            % disp(['Min candidates:', num2str(max(minAltitudeCandidates))]);
            % disp(minAltitudeCandidates);
            % disp(['Max candidates:', num2str(min(maxAltitudeCandidates))]);
            % disp(maxAltitudeCandidates);
            % 
            % validMaxAltidude = max(minAltitudeCandidates);
            % validMinAltitude = min(maxAltitudeCandidates);


            % Filtra la tabla Elda por la altura mínima y máxima
            % selecionada 

            % startAltitude = find(cell2mat(tfElda.elda_altitude) == app.HeightminSpinner.Value);
            % endAltitude =  find(cell2mat(tfElda.elda_altitude) == app.HeightmaxSpinner.Value);


%%
%%
%%


            %  for k=1 : height(tfElda.elpp_altitude)
            %     aAltitude = cell2mat(tfElda.elpp_altitude(k,1));
            %     aRange = cell2mat(tfElda.range(k,1));
            %     aTotal_power_channel = cell2mat(tfElda.total_power_channel(k,1));
            %     aRange_corrected_signal = cell2mat(tfElda.range_corrected_signal(k,1));
            % 
            %     afAltitude = [];
            %     afRange = [];
            %     afTotal_power_channel = [];
            %     afTotal_power_channel_mag = [];
            %     afRange_corrected_signal = [];
            %     magFactor = 10000;
            % 
            %     for x=1:length(aAltitude)
            %         if (aAltitude(x) > app.HeightminSpinner.Value) && (aAltitude(x) < app.HeightmaxSpinner.Value)
            %             afAltitude = [afAltitude, aAltitude(x)];
            %             afRange = [afRange, aRange(x)];
            %             afTotal_power_channel = [afTotal_power_channel, aTotal_power_channel(x)];
            %             afTotal_power_channel_mag = [afTotal_power_channel_mag, aTotal_power_channel(x) * magFactor];
            %             afRange_corrected_signal = [afRange_corrected_signal, aRange_corrected_signal(x)];
            %         end
            %     end
            % 
            %     %filtra la tabla
            %     tfElda.elpp_altitude{k,1} = num2cell(afAltitude');
            %     tfElda.range{k,1} = num2cell(afRange');
            %     tfElda.total_power_channel{k,1} = num2cell(afTotal_power_channel');
            %     tfElda.total_power_channel_mag{k,1} = num2cell(afTotal_power_channel_mag');
            %     tfElda.range_corrected_signal{k,1} = num2cell(afRange_corrected_signal');
            % 
            % 
            % end
            % 
            % assignin('base', 'tfElda', tfElda)
        
        
            % Graficar total_power_channel vs altitude para cada archivo brc_003 o brc_008
            % plot_NetCDF_ELPP(app.selected_measure_ID);
        
            % app.UIAxes.reset;
            % cla(app.UIAxes);
            % 
            % for k = 1:height(tfElda)
            % 
            %     tpc = tfElda.total_power_channel{k};
            %     if contains(tfElda.fileName{k}, '_1064_') 
            %         tpc = tfElda.total_power_channel_mag{k};
            %     end
            % 
            %     if startsWith(tfElda.fileName{k}, 'brc_003') || startsWith(tfElda.fileName{k}, 'brc_008')
            %         plot(app.UIAxes, cell2mat(tpc), cell2mat(tfElda.elpp_altitude{k}), 'Color', tfElda.attr_color{k});
            %     end
            % 
            %     if(k==1)
            %         hold(app.UIAxes, 'on'); 
            %     end
            % 
            % end


            % ylabel(app.UIAxes, 'Altitude');
            % xlabel(app.UIAxes, 'Range corrected signal');
            % 
            % 
            % aLegends = strings(1, height(tfElda));
            % 
            % for i = 1:height(tfElda)
            %     aLegends (i) = [num2str(tfElda.wavelength(i))];
            % end
            % 
            % for i = 1:height(tfElda)
            %     if isnan(cell2mat(tfElda.volumedepolarization(i)))
            %         disp('isnan');     
            % 
            %     else
            %         aLegends = [aLegends, ['VD', num2str(tfElda.wavelength(i))]];
            %     end
            % end
            % 
            % 
            % disp(aLegends);
            % legend(app.UIAxes, aLegends);
            % 
            % grid (app.UIAxes, 'on');
            % hold(app.UIAxes, 'off'); 
            % pause(2);
            % drawnow limitrate;
    
        end
        
        function [minELPPAltitude, maxELPPAltitude, minVDAltitude, maxVDAltitude] = getValidEldaAltitude(app)

            minELPPAltitude =''; 
            maxELPPAltitude =''; 
            minVDAltitude ='';
            maxVDAltitude ='';

            [tfElda, cont_elda] = getFilteredTable('elda', 1);

            minELPPAltitude = [min(cell2mat( tfElda(1,:).elpp_altitude))];
            maxELPPAltitude = [max(cell2mat( tfElda(1,:).elpp_altitude))];

            minAltitudeCandidates = minELPPAltitude;
            maxAltitudeCandidates = maxELPPAltitude;

            for k=1 : height(tfElda)
                VolDep = tfElda.volumedepolarization{k};
              
                if isnan(VolDep)
                    disp('Volume Depolarizaton not found... Ignoring Elda altitude')
                else
                    numericArray = cell2mat(cellfun(@(x) x(:), tfElda.elda_altitude(k,1), 'UniformOutput', false));
            
                    minAltitudeCandidates = [minAltitudeCandidates, min(numericArray(:))];
                    maxAltitudeCandidates = [maxAltitudeCandidates, max(numericArray(:))];
                    
                    disp(['Min. height for VD ', num2str(tfElda.wavelength(k,1)), ' is ', num2str(min(numericArray(:)))]);
                    disp(['Max. height for VD ', num2str(tfElda.wavelength(k,1)), ' is ', num2str(max(numericArray(:)))]);     

                    minVDAltitude = max(minAltitudeCandidates);
                    maxVDAltitude = min(maxAltitudeCandidates);
                end
            end
        end
        
        function urltofigurefiles = reloadFigureFiles(app)
            run("config_file.m")
            currentFolder = fileparts(mfilename('fullpath'));
            figureFolder = fullfile( currentFolder, CONFIG_output, app.SelectalidermeasurementIDDropDown.Value, app.SelectameasurementfiletoshowDropDown_2.Value, 'figures' );
            figureFileList = dir(fullfile(figureFolder, '*.mat'));
            app.SelectafiguretoshowDropDown.Items = strrep({figureFileList.name}, '.mat', '');

            if isempty(app.SelectafiguretoshowDropDown.Items)
                app.PlotfigureButton.Enable = 'off';
            else
                app.PlotfigureButton.Enable = 'on';
            end


            urltofigurefiles = figureFolder;
            disp('Figures...');
            disp(figureFolder);
            disp(figureFileList);
        end
    end
    
    methods (Access = public)
        
        function receiveAuth(app, auth)
            
            %Carga archivo de configuración para obtener las credenciales
            run("config_file.m")
            app.Auth.GenericU = auth.GenericU;
            app.Auth.GenericP = auth.GenericP;
            app.Auth.PersonalU = auth.PersonalU;
            app.Auth.PersonalP = auth.PersonalP;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        % Clear the space before start thea app
        clearAllTable();

        %Carga archivo de configuración para obtener las credenciales
        run("config_file.m");

       
        %% La parte del Credentials
        %Se vincula cada credencial con los campos de texto
        %correspondientes

        %Carga los valores de autenticación de la configuración
        app.SCCuserEditField.Value = CONFIG_GenericU;
        app.RealSCCPassword = CONFIG_GenericP;
        app.SCCpasswordEditField.Value = repmat('*', 1, length(app.RealSCCPassword)); % Mostrar asteriscos
        app.UsernameEditField.Value = CONFIG_PersonalU;
        app.RealPassword = CONFIG_PersonalP;
        app.PasswordEditField.Value = repmat('*', 1, length(app.RealPassword)); % Mostrar asteriscos


        %% La parte del ploting
        currentFolder = fileparts(mfilename('fullpath'));
        % run( fullfile(currentFolder, 'config_file.m') );
        parentFolder = fileparts(currentFolder);
        app.measureIDFolder = fullfile( currentFolder, CONFIG_output);

        reloadMeasuresID(app);
        app.SelectameasurementfiletoshowDropDown_2.Items = findSpecificFolders(app.SelectalidermeasurementIDDropDown.Value);

        reloadFigureFiles(app)
        figureFolder = fullfile( currentFolder, CONFIG_output, app.SelectalidermeasurementIDDropDown.Value, 'figures' );
        figureFileList = dir(fullfile(figureFolder, '*.fig'));
        app.SelectafiguretoshowDropDown.Items = {figureFileList.name};


        %% La parte de la documentación

        % Define la ruta del directorio donde se encuentran los archivos
        % publicados para funciones
        dirPath = fullfile( currentFolder, CONFIG_help_root);
        files = dir(fullfile(dirPath, 'functions', '*.html'));
        fileNames = {files.name};
        
        for k = 1:length(files)
            % Añade cada archivo como un nodo hijo del nodo principal
            fileNameFunct = strrep(files(k).name, '_doc.html', '');
            uitreenode(app.FunctionsNode, 'Text', fileNameFunct, 'NodeData', fullfile(dirPath, 'functions', files(k).name));
        end

        % Define la ruta del directorio donde se encuentran los archivos
        % publicados para funciones
        dirPath = fullfile( currentFolder, CONFIG_help_root);
        files = dir(fullfile(dirPath, 'guides', '*.html'));
        fileNames = {files.name};
        
        for k = 1:length(files)
            % Añade cada archivo como un nodo hijo del nodo principal
            fileNameGuides = strrep(files(k).name, '.html', '');
            fileNameGuides = strrep(fileNameGuides, '_', ' ');
            uitreenode(app.UserGuidesNode, 'Text', fileNameGuides, 'NodeData', fullfile(dirPath, 'guides', files(k).name));
        end


        %% La parte del prebuild

                app.D1_LPhotometerlidarButton.Enable = 'off';
                app.D1P_LPhotom_pollidarButton.Enable = 'off';
                app.D1_L_VDPhotometerlidar_depButton.Enable = 'off';
                app.D1P_L_VDPhotom_pollidar_depButton.Enable='off';
            global fileLoaded
            fileLoaded=false;

            % by default the database button is desabled
            app.LoadButton.Enable = 'off';
            app.ViewfiguresCheckBox.Enable = 'off';

            %% Create the repsotirories for AERONET Files
            %  Crea la estructura de directorios para los recursos AERONET si no existe
            %  Los ficheros esperados de AERONET debe colocarse en esta
            %  carpeta manualmente
            
            run('config_file.m');
            currentFolder = fileparts(mfilename('fullpath'));  
            URL_aeronet = fullfile(currentFolder, CONFIG_AERONET_folder);
        
            if ~exist(URL_aeronet, 'dir')
                mkdir(URL_aeronet);
                disp('Carpeta de recursos AERONET creada.');
            else
                disp('La carpeta de recursos AERONET ya existe, no se ha sobrescrito.');
            end

            drawnow;
            [app.foundFilesAOD, app.foundFilesAlm, app.foundFilesAlp, app.foundFilesAll] = loadResources(app);

            logMessage(app, ['RESOURCES: Analyzing AOD... ', num2str(app.foundFilesAOD), ' file(s) loaded']);
            logMessage(app, ['RESOURCES: Analyzing ALM... ', num2str(app.foundFilesAlm), ' file(s) loaded']);
            logMessage(app, ['RESOURCES: Analyzing ALP... ', num2str(app.foundFilesAlp), ' file(s) loaded']);
            logMessage(app, ['RESOURCES: Analyzing ALL... ', num2str(app.foundFilesAll), ' file(s) loaded']);
           
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %%CONVERSIÓN DE BBDD .csv a excel y a variable del workspace
            dbError ='';
            %fileLoaded=false;
    
    
            % Abre un cuadro de diálogo para seleccionar el archivo CSV de entrada
            [inputFileName, inputPath] = uigetfile('*.csv', 'Seleccione el archivo CSV de entrada');
    
            disp(['Archivo seleccionado: ', inputFileName]);
    
            if isequal(inputFileName, 0)
                 dbError = 'No se seleccionó ningún archivo.';
            elseif ~endsWith(inputFileName,'.csv')
                dbError = 'Solo CSVs por favor.';
            else
                fullInputFileName = fullfile(inputPath, inputFileName);
                disp(['Archivo de base de datos seleccionado: ', inputFileName]);
    
                try
                    disp('Leyendo archivo CSV...');
                    logMessage(app, 'DDBB: Reading file...');

                    tDatabase = readtable(fullInputFileName);
                    disp('Archivo CSV leído correctamente.');
                    logMessage(app, 'DDBB: read correctly');


                    %3- Asigna T al workspace base
                    assignin('base', 'tDatabase', tDatabase);
                
                    disp('Tabla tDatabase asignada al workspace base.');
    
                    logMessage(app, 'DDBB: assigned in workspace.');
                    logMessage(app, 'DDBB: process finished correctly.');
                    
                catch ME
                
                    dbError = ME.message;
                end

                figure(app.UIFigure);
            end
            
            if ~isempty(dbError)
                logMessage(app, dbError);
            end


            try
                % Construir la ruta al archivo en la subcarpeta "config"

                run("config_file.m");

                currentFolder = fileparts(mfilename('fullpath'));
                subfolder = CONFIG_folder_SCC;
                filename = CONFIG_config_SCC; 
                file_path = fullfile(currentFolder, subfolder, filename);

                % Leer el archivo Excel y extraer la primera columna
                data = readtable(file_path, 'Range', 'A:A');

                % Extraer los system_ID
                app.system_ids = data.(1); % Asumiendo que la primera columna contiene los system_ID

            catch ME
                % Manejo de errores en caso de que el archivo no se pueda leer
                disp(['Error al leer el archivo Excel: ', ME.message]);
                uialert(app.UIFigure, 'No se pudo cargar el archivo Excel de configuración', 'Error de carga');
            end

            
            if ~isempty(app.system_ids)
                app.StartDateDatePicker.Enable = 'on';
                app.StopdateDatePicker.Enable = 'on';
                app.FilterdatesButton.Enable = 'on';
            end

        end

        % Button pushed function: FilterdatesButton
        function FilterdatesButtonPushed(app, event)

            %1- Comprobamos que la tabla 'tMeasures' existe en el workspace base
            if evalin('base', 'exist(''tDatabase'', ''var'')')
                % Acceder a la tabla T desde el workspace base
                tDatabase = evalin('base', 'tDatabase');
                logMessage(app, 'Measurement IDs loaded correctly.');
                app.SelectedIDLabelDropDown.Enable = 'on';
                app.AcceptButton.Enable = 'on';

            else
                uialert(app.UIFigure, 'La tabla tDatabase no está definida en el workspace base.', 'Error de Variable No Definida');
   
    %             error('La variable T no está definida en el workspace base.');
                return;
            end
            
            %%PRIMER FILTRO: IDENTIFICADOR DEL SISTEMA SELECCIONADO
            %2- Definimos el nombre de la columna y el valor de filtrado
            columnName = 'system_id'; % Nombre de la columna a filtrar: IDENTIFICADOR DEL SISTEMA
            filterValue=app.system_ids;
    
            %3-Mostramos el valor del filtro en la consola
            disp(['Filtrando por ', columnName, ' con valor: ']);
            disp(filterValue);

            %4-Comprobamos que la columna existe en la tabla
            if ismember(columnName, tDatabase.Properties.VariableNames)
                % Filtrar la tabla según el valor especificado
                filterCriteria = ismember(tDatabase.(columnName), app.system_ids);
                filteredTable = tDatabase(filterCriteria, :);
            end
                
            %5-Mostramos la tabla filtrada por system_id en la consola
            %disp('Tabla filtrada por system_id:');
            % disp(filteredTable);
                
            %6-Asignamos la tabla filtrada al workspace base como 'tfMeasures'
            assignin('base', 'tfMeasures', filteredTable);
                
            %%SEGUNDO FILTRO: FECHAS DE INICIO Y FIN SELECCIONADAS
            %1-Definimos los nombres de las columnas y los valores de filtrado para las fechas
            i_st = app.StartDateDatePicker.Value;
            i_st_conv = datestr(i_st, 'yyyy-mm-dd');    
        
            i_stop = app.StopdateDatePicker.Value;
            i_stop_conv = datestr(i_stop, 'yyyy-mm-dd');
            startDateColumn = 'profile_start_time';
            endDateColumn = 'profile_end_time';
            
            %2-Convertimos las fechas de result a formato datetime
            %Hay que añadir la hora, ya que en el date picker solo aparece la
            %fecha del estilo yyyy-mm-dd
            startDate=i_st_conv + " 00:00:00"; 
            endDate=i_stop_conv + " 23:59:59";
            startDateFilter = datetime(startDate, "InputFormat",'yyyy-MM-dd HH:mm:ss');
            endDateFilter = datetime(endDate, "InputFormat",'yyyy-MM-dd HH:mm:ss');
    
            disp('Tabla filtrada por Id:');
            disp(num2str(height(filteredTable)));

            
            %3- Comprobamos que las columnas de fecha existen en la tabla filtrada
            if ismember(startDateColumn, filteredTable.Properties.VariableNames) && ...
               ismember(endDateColumn, filteredTable.Properties.VariableNames)
               
                %4-Convertir las columnas de fecha en la tabla a datetime si no lo están
                if ~isdatetime(filteredTable.(startDateColumn))
                    filteredTable.(startDateColumn) = datetime(filteredTable.(startDateColumn), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                end
                if ~isdatetime(filteredTable.(endDateColumn))
                    filteredTable.(endDateColumn) = datetime(filteredTable.(endDateColumn), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                end
                    
                %5- Filtramos la tabla según el rango de fechas
                dateFilterCriteria = (filteredTable.(startDateColumn) >= startDateFilter) & ...
                                     (filteredTable.(endDateColumn) <= endDateFilter);
                filteredTable = filteredTable(dateFilterCriteria, :);
                
                %6- Mostrar la tabla filtrada por rango de fechas en la consola
                disp('Tabla filtrada por rango de fechas:');
                disp(num2str(height(filteredTable)));
    
            end

%         end
            assignin('base', 'T_filt', filteredTable);
%             show_unique_identifiers(app, T_filt);
            % Obtener los identificadores únicos de la columna measurement_id
            uniqueIDs = unique(filteredTable.measurement_id);
            
            % Crear una lista de identificadores únicos como cadenas
            uniqueIDsStr = cellstr(uniqueIDs);
            
            % Asignar los identificadores únicos al dropdown
            app.SelectedIDLabelDropDown.Items = uniqueIDsStr;
            logMessage(app, 'End filter');


        end

        % Value changed function: SelectedIDLabelDropDown
        function SelectedIDLabelDropDownValueChanged(app, event)


         app.selected_measure_ID = app.SelectedIDLabelDropDown.Value;
         app.AcceptButton.Enable = 'on';

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)

            
            currentFile = mfilename('fullpath'); % Obtiene la ruta completa del archivo actual
            rootFolder = fileparts(currentFile); % Subir un nivel en el directorio

            addpath( fullfile(rootFolder, 'tools') );
            

            app.selected_measure_ID = app.SelectedIDLabelDropDown.Value;
            app.AcceptButton.Enable = 'off';

            logMessage( app, '> ELDA: Trying to download files...');
            pause(0.5);
            [resDownloadEldaError, resDownloadEldaMessage, resMeasureID] = download(app.selected_measure_ID, app.Auth, 'ELDA');

            logMessage( app, '> ELPP: Trying to download files...');
            pause(0.5);
            [resDownloadElppError, resDownloadElppMessage, resMeasureID] = download(app.selected_measure_ID, app.Auth, 'ELPP');
            
            logMessage(app, ['ELDA files: ', resDownloadEldaMessage]);
            logMessage(app, ['ELPP files: ', resDownloadElppMessage]);
            
            app.AcceptButton.Enable = 'on';

            disp('resDownloadEldaError');
            disp(resDownloadEldaError);

            if(resDownloadEldaError == -1 || resDownloadElppError == -1)

                errordlg('ERROR: ELDA or ELPP files missing and it can''t be downloaded', 'Error');

            else
                
                app.CurrentIDEditField.Value = resMeasureID;
                % Limpia las tablas previas ELDA
                clearWSTNByType('elda');
    
                app.foundFilesELDA = read_NetCDF(app.selected_measure_ID);
    
                disp(['foundFilesNetCDF: ', num2str(app.foundFilesELDA)]);
     
                
                %Busca el nombre de la tabla Elda para cargarla
                tEldaElpp = [];
                filterBRC_008 = ''; 
    
                elda_name = getWSTNByType('elda').table_name;
                if ~isempty(elda_name)
                    tEldaElpp = evalin('base', elda_name{1});
                end
    
                %tEldaElppFilteredByBRC_008 = tEldaElpp(filterBRC_008, :);


                % Por si alguna vez usamos config. personalizada de BRC_
                %app.EldaElppDropDown.Enable = 'off';
                %app.EldaElppDropDown.Items = tEldaElppFilteredByBRC_008.fileName;


                % By default spinner are disabled to avoid invalid inputs
                
                app.HeightminSpinner.Enable = 'off';
                app.HeightmaxSpinner.Enable = 'off';

                app.RButton.Enable = 'off';
                app.RButton_2.Enable = 'off';

                %%%  All config disabled
                app.D1P_L_VDPhotom_pollidar_depButton.Enable='off';
                app.D1P_LPhotom_pollidarButton.Enable = 'off';
                app.D1_L_VDPhotometerlidar_depButton.Enable = 'off';
                app.SendButton.Enable='off';
                app.D1_LPhotometerlidarButton.Enable = 'off';
    
                %%Control de que hay demasiados archivos
                
                if app.foundFilesELDA > 0
                    [statusWaveLength, message_wavelength, status_1064, message_1064, status_0532, message_0532, status_0355, message_0355] = checkElda(); 
                end

                if (app.foundFilesAOD > 2) || (app.foundFilesAlm > 1) || (app.foundFilesAll > 1)
    
                    logMessage(app, 'ERROR: Use one AOD and ALM. If you are using optional ALP or ALL files, ensure that you are using one only in your repository.');
                    errordlg('ERROR: Use one AOD and ALM. If you are using optional ALP or ALL files, ensure that you are using one only in your repository.', 'Error');
    
                elseif (app.foundFilesAOD == 0) || (app.foundFilesAlm == 0)
                    logMessage(app, '> ERROR: AOD or ALM files can''t be found in the repository.');
    
                elseif app.foundFilesELDA == 0
                    logMessage(app, '> ERROR: ELDA file missing or invalid. Check files in the repository.');
    
                elseif statusWaveLength ~=1
    
                    logMessage(app, ['> ERROR: ', message_wavelength]);
                    logMessage(app, ['> ERROR: ', message_1064]);
                    logMessage(app, ['> ERROR: ', message_0532]);
                    logMessage(app, ['> ERROR: ', message_0355]);
    
                else
    
                    logMessage(app, ['ELDA/ELPP: ', message_wavelength]);
    
                    %%% Get Elda Atitude limits and assign to GUI
                    [app.heightELPPLimitMin, app.heightELPPLimitMax, app.heightVDLimitMin, app.heightVDLimitMax] = getValidEldaAltitude(app);
                    

                    % Enable the ELPP/VD Switch if VD altitude is found
                    app.AltiudeSwitch.Enable = 'off';
                    if isempty(app.heightVDLimitMin) || isempty(app.heightVDLimitMax)
                        app.AltiudeSwitch.Enable = 'off';
                    else
                        app.AltiudeSwitch.Enable = 'on';
                    end

                    % app.heightLimitMin = min(cell2mat( tEldaElpp(1,:).elpp_altitude));
                    % app.heightLimitMax = max(cell2mat( tEldaElpp(1,:).elpp_altitude));
    
                    % Configura los spinners 
                    app.HeightminSpinner.ValueDisplayFormat = '%.0f';
                    app.HeightmaxSpinner.ValueDisplayFormat = '%.0f';
    
                    if strcmp(app.AltiudeSwitch.Value,'ELPP')
                        app.HeightminSpinner.Limits = [app.heightELPPLimitMin, app.heightELPPLimitMax];
                        app.HeightmaxSpinner.Limits = [app.heightELPPLimitMin, app.heightELPPLimitMax];
                    else
                        app.HeightminSpinner.Limits = [app.heightVDLimitMin, app.heightVDLimitMax];
                        app.HeightmaxSpinner.Limits = [app.heightVDLimitMin, app.heightVDLimitMax];
                    end

                    app.HeightminSpinner.Enable = 'on';
                    app.HeightmaxSpinner.Enable = 'on';

                    app.RButton.Enable = 'on';
                    app.RButton_2.Enable = 'on';

                    app.PlotdataButton.Enable = 'on';
    

                    if strcmp(app.AltiudeSwitch.Value,'ELPP')
                        app.HeightminSpinner.Value = app.heightELPPLimitMin;
                        app.HeightmaxSpinner.Value = app.heightELPPLimitMax;
                    else
                        app.HeightminSpinner.Value = app.heightVDLimitMin;
                        app.HeightmaxSpinner.Value = app.heightVDLimitMax;
                     end


                    %
                    % Get all filtered data
                    [tfLev15_1, cont_lev15_1] = getFilteredTable('lev15', 1);
                    % [tfLev15_2, cont_lev15_2] = getFilteredTable('lev15', 2); # for lunar version
                    [tfAlm, cont_alm] = getFilteredTable('alm', 1);
                    [tfAlp, cont_alp] = getFilteredTable('alp', 1);
                    [tfAll, cont_all] = getFilteredTable('all', 1);
                    [tfElda, cont_elda] = getFilteredTable('elda', 1);

                    %
                    % Assign filtered data to Workspace tables
                    assignin('base', 'tfLev15_1', tfLev15_1);
                    % assignin('base', 'tfLev15_2', tfLev15_2); # for lunar version
                    assignin('base', 'tfAlm', tfAlm);
                    assignin('base', 'tfAlp', tfAlp);
                    assignin('base', 'tfAll', tfAll);
                    assignin('base', 'tfElda', tfElda);

                    % Check if ALM & AOD have info in the filtered table

                    disp(['T-AOD:', num2str(cont_lev15_1)])
                    disp(['T-ALM:', num2str(cont_alm)])


                    if(str2double(cont_lev15_1) == 0 || str2double(cont_alm) == 0 || str2double(cont_all) == 0)
                        warndlg('Your filter hasn''t any result for AOD / ALM / ALL tables. Try to select other range of dates' , 'Data not found for your filter', 'modal');
                    end


                    % Devuelve las configuraciones disponibles
                    [is_D1_L, is_D1P_L, is_D1_L_VD, is_D1P_L_VD, messageFromCheckSendData] = checkSendDataConfigs();

                    if (is_D1_L)
                        app.D1_LPhotometerlidarButton.Enable = 'on';
                    end

                    if (is_D1P_L)
                        app.D1P_LPhotom_pollidarButton.Enable = 'on';
                    end

                    if (is_D1_L_VD)
                        app.D1_L_VDPhotometerlidar_depButton.Enable = 'on';
                    end                    

                    if (is_D1P_L_VD)
                        app.D1P_L_VDPhotom_pollidar_depButton.Enable='on';
                    end
                    

                    % if one of them is true the send button is activated
                    if (is_D1_L || is_D1P_L || is_D1_L_VD || is_D1P_L_VD)
                        app.SendButton.Enable='on';
                    end

                    % Write in log messages received
                    if iscell(messageFromCheckSendData)
                        for i = 1:length(messageFromCheckSendData)
                            logMessage(app, messageFromCheckSendData{i});
                        end
                    else
                        % if it's a single message
                        logMessage(app, messageFromCheckSendData);
                    end
                end
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            disp('plot button down called')
            disp(['UIAxes structure lenght: ', num2str(length(app.dataStruct))]);

            cla(app.UIAxes, 'reset');

            % Graficar total_power_channel vs altitude para cada archivo brc_003 o brc_008


            for k = 1:length(app.dataStruct)
                if isfield(app.dataStruct(k), 'total_power_channel')
                    pause(1);
                    plot(app.UIAxes, app.dataStruct(k).total_power_channel, app.dataStruct(k).altitude);
                    if(k==1)
                        hold(app.UIAxes, 'on'); 
                    end
    
                end
            end
            hold(app.UIAxes, 'off'); 
        end

        % Selection changed function: SettingsButtonGroup
        function SettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SettingsButtonGroup.SelectedObject;
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)

            cfgSelected = app.SettingsButtonGroup.SelectedObject.Text;
            GARRLiC_file_name = '';
            errorVolumePolarization = false;

            if strcmpi(cfgSelected , 'D1P_L_VD: Photom_pol + lidar_dep')
                [GARRLiC_file_name, errorVolumePolarization] = sendData_D1P_L_VD( app.CurrentIDEditField.Value, app.HeightminSpinner.Value, app.HeightmaxSpinner.Value, app.ViewfiguresCheckBox.Value, app.ExportLIDARCheckBox.Value);
            elseif strcmpi(cfgSelected, 'D1P_L: Photom_pol + lidar')
                GARRLiC_file_name = sendData_D1P_L( app.CurrentIDEditField.Value, app.HeightminSpinner.Value, app.HeightmaxSpinner.Value, app.ViewfiguresCheckBox.Value, app.ExportLIDARCheckBox.Value);
            elseif strcmpi(cfgSelected, 'D1_L_VD: Photometer + lidar_dep')
                [GARRLiC_file_name, errorVolumePolarization] = sendData_D1_L_VD( app.CurrentIDEditField.Value, app.HeightminSpinner.Value, app.HeightmaxSpinner.Value, app.ViewfiguresCheckBox.Value, app.ExportLIDARCheckBox.Value);
            elseif strcmpi(cfgSelected, 'D1_L: Photometer + lidar')
                GARRLiC_file_name = sendData_D1_L( app.CurrentIDEditField.Value, app.HeightminSpinner.Value, app.HeightmaxSpinner.Value, app.ViewfiguresCheckBox.Value, app.ExportLIDARCheckBox.Value);
            end

            if ~isempty(GARRLiC_file_name)
                logMessage(app, ['GARRLiC: output file ', GARRLiC_file_name, ' written correctly']);
            else
                logMessage(app, 'GARRLiC: error writing the output file');
                if(errorVolumePolarization)
                    logMessage(app, '--------------------------------------------------------------------');
                    logMessage(app, 'Affected fields: VD355, VD532, RangeVD355, RangeVD532');
                    logMessage(app, 'Please check LIDAR Volume Polarization avaliability in ''008'' files');
                    logMessage(app, 'ERROR: Volume Polarization missing data');                    
                    logMessage(app, '--------------------------------------------------------------------');
                end
            end
        end

        % Value changed function: HeightminSpinner
        function HeightminSpinnerValueChanged(app, event)

             if strcmp(app.AltiudeSwitch.Value,'ELPP')
                app.HeightminSpinner.Limits = [app.heightELPPLimitMin, app.heightELPPLimitMax];
             else
                app.HeightminSpinner.Limits = [app.heightVDLimitMin, app.heightVDLimitMax];
             end

        end

        % Value changed function: HeightmaxSpinner
        function HeightmaxSpinnerValueChanged(app, event)
             if strcmp(app.AltiudeSwitch.Value,'ELPP')
                app.HeightmaxSpinner.Limits = [app.heightELPPLimitMin, app.heightELPPLimitMax];
             else
                app.HeightmaxSpinner.Limits = [app.heightVDLimitMin, app.heightVDLimitMax];
             end
        end

        % Callback function
        function EldaElppDropDownValueChanged(app, event)
          
  %          value = app.EldaElppDropDown.Value;
            
        end

        % Button pushed function: PlotdataButton
        function PlotdataButtonPushed(app, event)
            plotter(app);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
             if strcmp(app.AltiudeSwitch.Value,'ELPP')
                app.HeightmaxSpinner.Value = app.heightELPPLimitMax;
             else
                app.HeightmaxSpinner.Value = app.heightVDLimitMax;
             end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            if strcmp(app.AltiudeSwitch.Value,'ELPP')
                app.HeightminSpinner.Value = app.heightELPPLimitMin;
             else
                app.HeightminSpinner.Value = app.heightVDLimitMin;
            end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            app.DataBaseTextArea.Value = '';
            app.logger = [];
        end

        % Button pushed function: AeronetButton
        function AeronetButtonPushed(app, event)
        % Especifica la ruta de la carpeta que deseas abrir
            run('config_file.m')
            currentFolder = fileparts(mfilename('fullpath'));  
            URL_output = fullfile(currentFolder, CONFIG_AERONET_folder);

            if isfolder(URL_output)
                % Abre la carpeta en el explorador de archivos del sistema
                winopen(URL_output);
            else
                % Mostrar un mensaje de error si la carpeta no existe
                errordlg('La carpeta especificada no existe', 'Error');
            end
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
             % Especifica la ruta de la carpeta que deseas abrir
            run('config_file.m')
            currentFolder = fileparts(mfilename('fullpath'));  
            URL_output = fullfile(currentFolder, CONFIG_output);

            if isfolder(URL_output)
                % Abre la carpeta en el explorador de archivos del sistema
                winopen(URL_output);
            else
                % Mostrar un mensaje de error si la carpeta no existe
                errordlg('La carpeta especificada no existe', 'Error');
            end            
        end

        % Button pushed function: LIDARButton
        function LIDARButtonPushed(app, event)

            % Especifica la ruta de la carpeta que deseas abrir
            run('config_file.m')
            currentFolder = fileparts(mfilename('fullpath'));  
            URL_output = fullfile(currentFolder, CONFIG_LIDAR_folder);

            if isfolder(URL_output)
                % Abre la carpeta en el explorador de archivos del sistema
                winopen(URL_output);
            else
                % Mostrar un mensaje de error si la carpeta no existe
                errordlg('La carpeta especificada no existe', 'Error');
            end 
        end

        % Value changed function: AltiudeSwitch
        function AltiudeSwitchValueChanged(app, event)
            
            [app.heightELPPLimitMin, app.heightELPPLimitMax, app.heightVDLimitMin, app.heightVDLimitMax] = getValidEldaAltitude(app);

            if strcmp(app.AltiudeSwitch.Value,'ELPP')
                app.HeightminSpinner.Limits = [app.heightELPPLimitMin, app.heightELPPLimitMax];
                app.HeightmaxSpinner.Limits = [app.heightELPPLimitMin, app.heightELPPLimitMax];
                app.HeightminSpinner.Value = app.heightELPPLimitMin;
                app.HeightmaxSpinner.Value = app.heightELPPLimitMax;
            else
                app.HeightminSpinner.Limits = [app.heightVDLimitMin, app.heightVDLimitMax];
                app.HeightmaxSpinner.Limits = [app.heightVDLimitMin, app.heightVDLimitMax];
                app.HeightminSpinner.Value = app.heightVDLimitMin;
                app.HeightmaxSpinner.Value = app.heightVDLimitMax;

            end            
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
        %Carga archivo de configuración para obtener las credenciales
            run("config_file.m")

            app.Auth.GenericU = app.SCCuserEditField.Value;
            app.Auth.GenericP = app.RealSCCPassword;
            
            app.Auth.PersonalU = app.UsernameEditField.Value;
            app.Auth.PersonalP = app.RealPassword;

            disp(['RealSCCPassword', app.RealSCCPassword]);
            disp(['RealPassword', app.RealPassword]);

            [isValid, message] = AuthSCCAccess(app.Auth);
            
            if isValid
                app.StatusLamp.Color = [0, 1, 0];
                app.LoadButton.Enable = 'on';
                app.ViewfiguresCheckBox.Enable = 'on';

                %setappdata(0, 'SharedData', app.Auth);
                %app.UIFigure.Visible='off';
                %app2=GUI;
                %app2.UIFigure.Visible='on';
                %app2.receiveAuth(app.Auth);
            else
                errordlg(message);
                app.StatusLamp.Color = [1, 0, 0];
                app.LoadButton.Enable = 'off';
                app.ViewfiguresCheckBox.Enable = 'off';
            end
        end

        % Value changed function: SelectalidermeasurementIDDropDown
        function SelectalidermeasurementIDDropDownValueChanged(app, event)
            app.SelectameasurementfiletoshowDropDown_2.Items = findSpecificFolders(app.SelectalidermeasurementIDDropDown.Value);   
            reloadFigureFiles(app);
            if(~isempty(app.SelectafiguretoshowDropDown.Items))
                app.PlotfigureButton.Enable = 'on';
            else
                app.PlotfigureButton.Enable = 'off';
            end
        end

        % Drop down opening function: SelectalidermeasurementIDDropDown
        function SelectalidermeasurementIDDropDownOpening(app, event)
            app.SelectameasurementfiletoshowDropDown_2.Items = findSpecificFolders(app.SelectalidermeasurementIDDropDown.Value);
            reloadFigureFiles(app);
        end

        % Button pushed function: SavefiguresButton
        function SavefiguresButtonPushed(app, event)
            %%! SavefiguresButtonPushed(app, event)
        %%! Descripción
        %!
        %! Lanza la petición de ploteo para la medida y configuración
        %! seleccionada en los selectores correspondientes de la interfaz.
        %1 Previamente identifica el tipo de configuración seleccionada y
        % ejecuta la función de ploteo requerida.
        %!
        %! *PARÁMETROS DE ENTRADA*
        %!
        %! * |app: [app object]| -
        %!      Instancia de la clase de la aplicación que estás creando
        %!
        %! * |event: [event object]| -
        %!      Ejecuta la función cuando se produce el evento asignado. En
        %!      este caso la pulsación del botón

        %! *VALORES DE SALIDA*
        %!
        %! * No devuelve ningún valor
        %!     
        %1
        %1 *Nota 1:* Se distinguen en código las diferentes configuraciones
        % por motivos de escalabilidad en futuras versiones pero en la
        % actual las cuatro configuraciones invocan la misma función de
        % ploteo.
        %!
        %! *Nota 2:* El botón se deshabilita una vez pulsado hasta que finaliza
        %! el ploteado de los datos. De esta manera se impide iniciar un nuevo
        %! ploteo antes de finalizar el anterior y 
        %! el consecuente mensaje de error del sistema.


            %Plotting GRASP outputs
            
            % clear all
            % clc
            %%
            %% This part has been moved to SCC Module
            %Read ELDA products 
            % ElDA_Profiles_Correctly = ELDA_Profiles();
            %%
            %Write down all wavelengths used in GRASP inputs.
            Lambda = [355,380,440,500,532,675,870,1020,1064,1640];
            Waves_AERONET2 = [380,440,500,675,870,1020,1640];
            %These values are from GRASP to the VSD.
            size_binsF = [0.05,0.0656,0.086,0.11294,0.14818,0.19443,0.25511,0.33472,0.43917,0.57623];
            size_binsC = [0.33472,0.43917,0.57623,0.75605,0.99199,1.3016,1.7078,2.2407,2.94,3.8575,5.0613,6.6407,8.7131,11.432,15];
            % size_bins = [0.05,0.0656,0.086,0.11294,0.14818,0.19443,0.25511,0.33472,0.43917,0.57623,0.75605,0.99199,1.3016,1.7078,2.2407,2.94,3.8575,5.0613,6.6407,8.7131,11.432,15];
            %Aerosol volume concentration changes for each case run in GRASP
            % AVCF = input('Aerosol volume concentration - Fine mode: ');
            % AVCC = input('Aerosol volume concentration - Coarse mode: ');


            currentFolder = fileparts(mfilename('fullpath'));
            run('config_file.m');
            % parentFolder = fileparts(currentFolder);
            measureIDFolder = fullfile( currentFolder, CONFIG_output);

            fullPath = fullfile(measureIDFolder, app.SelectalidermeasurementIDDropDown.Value, app.SelectameasurementfiletoshowDropDown_2.Value);
            % app.SavefiguresButton.Enable = 'off';
            pause(1);

            if startsWith(app.SelectameasurementfiletoshowDropDown_2.Value, 'D1_L-', 'IgnoreCase', true)
                GRASP_Plot_Correctly = grasp_plotting_UPC(fullPath, 'D1_L', Lambda,Waves_AERONET2,size_binsF,size_binsC);
                logMessage(app, ['OUT or GRASP data file not found for D1_L configuration in measure ', app.SelectalidermeasurementIDDropDown.Value]);

            elseif startsWith(app.SelectameasurementfiletoshowDropDown_2.Value, 'D1_L_VD-', 'IgnoreCase', true)
                GRASP_Plot_Correctly = grasp_plotting_UPC(fullPath, 'D1_L_VD', Lambda,Waves_AERONET2,size_binsF,size_binsC);
                logMessage(app, ['OUT or GRASP data file not found for D1_L_VD configuration in measure ', app.SelectalidermeasurementIDDropDown.Value]);

            elseif startsWith(app.SelectameasurementfiletoshowDropDown_2.Value, 'D1P_L-', 'IgnoreCase', true)
                GRASP_Plot_Correctly = grasp_plotting_UPC(fullPath, 'D1P_L', Lambda,Waves_AERONET2,size_binsF,size_binsC);
                logMessage(app, ['OUT or GRASP data file not found for D1P_L configuratio in measure ', app.SelectalidermeasurementIDDropDown.Value]);

            elseif startsWith(app.SelectameasurementfiletoshowDropDown_2.Value, 'D1P_L_VD-', 'IgnoreCase', true)
                GRASP_Plot_Correctly = grasp_plotting_UPC(fullPath, 'D1P_L_VD', Lambda,Waves_AERONET2,size_binsF,size_binsC);
                logMessage(app, ['OUT or GRASP data file not found for D1P_L_VD configuration in measure ', app.SelectalidermeasurementIDDropDown.Value]);

            else
                logMessage(app, 'Unknown configuration ');
                disp('Unknown configuration ');
            end
            
            if(GRASP_Plot_Correctly)
                logMessage(app, ['OUT or GRASP data file not found for your configuration in measure ', app.SelectalidermeasurementIDDropDown.Value]);
            end

            app.SavefiguresButton.Enable = 'on';
  
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
        %%! RButtonPushed(app, event)
        %%! Descripción
        %!
        %! Actualiza las medidas disponibles en el selector.
        %! ütil cuando se incorpora una nueva media después de haber iniciado la interfaz de ploteo
        %! Para hacerlo invoca la función |reloadMeasuresID(app)|
        %!
        %! *PARÁMETROS DE ENTRADA*
        %!
        %! * |app: [app object]| -
        %!      Instancia de la clase de la aplicación que estás creando
        %!
        %! * |event: [event object]| -
        %!      Ejecuta la función cuando se produce el evento asignado. En
        %!      este caso la pulsación del botón

        %! *VALORES DE SALIDA*
        %!
        %! * No devuelve ningún valor


            reloadMeasuresID(app);
            reloadFigureFiles(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNode = event.SelectedNodes;
                if ~isempty(selectedNode) && ~isempty(selectedNode.NodeData)
                    % Carga el archivo HTML en el componente HTML
                    app.HTML.HTMLSource = selectedNode.NodeData;
                end            
        end

        % Value changing function: SCCpasswordEditField
        function SCCpasswordEditFieldValueChanging(app, event)
            % Obtener el valor visible actual en el campo (lo que el usuario escribe)
            visibleText = event.Value;  % Usamos el nuevo valor del evento en ValueChanging
            
            % Actualizar la contraseña real directamente desde el campo visible
            app.RealSCCPassword = visibleText;  % Actualizar la contraseña real con el valor visible
            
            % Reemplazar el contenido visible del campo con asteriscos
            numChars = length(app.RealSCCPassword);
            app.SCCpasswordEditField.Value = repmat('*', 1, numChars);            
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            % Obtener el valor visible actual en el campo (lo que el usuario escribe)
            visibleText = event.Value;  % Usamos el nuevo valor del evento en ValueChanging
            
            % Actualizar la contraseña real directamente desde el campo visible
            app.RealPassword= visibleText;  % Actualizar la contraseña real con el valor visible
            
            % Reemplazar el contenido visible del campo con asteriscos
            numChars = length(app.RealPassword);
            app.PasswordEditField.Value = repmat('*', 1, numChars);            
        end

        % Value changed function: SelectameasurementfiletoshowDropDown_2
        function SelectameasurementfiletoshowDropDown_2ValueChanged(app, event)
            reloadFigureFiles(app);
            if(~isempty(app.SelectafiguretoshowDropDown.Items))
                app.PlotfigureButton.Enable = 'on';
            else
                app.PlotfigureButton.Enable = 'off';
            end
        end

        % Button pushed function: PlotfigureButton
        function PlotfigureButtonPushed(app, event)
            
            urltoDatafigure = fullfile(reloadFigureFiles(app), app.SelectafiguretoshowDropDown.Value);
            dataToPlot(app, urltoDatafigure);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            resetUIAxes(app)
        end

        % Drop down opening function: 
        % SelectameasurementfiletoshowDropDown_2
        function SelectameasurementfiletoshowDropDown_2Opening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.DataBaseTextArea = uitextarea(app.UIFigure);
            app.DataBaseTextArea.Editable = 'off';
            app.StopdateDatePickerLabel_4 = uilabel(app.UIFigure);
            app.StopdateDatePickerLabel_4.Text = 'Log |';
            app.DataCrossingLabel = uilabel(app.UIFigure);
            app.DataCrossingLabel.Text = ' DataCrossing';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Clear';
            app.AeronetButton = uibutton(app.UIFigure, 'push');
            app.AeronetButton.ButtonPushedFcn = createCallbackFcn(app, @AeronetButtonPushed, true);
            app.AeronetButton.Icon = fullfile(pathToMLAPP, 'Interface', 'icon-folder-white.png');
            app.AeronetButton.Text = 'Aeronet';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Icon = fullfile(pathToMLAPP, 'Interface', 'icon-folder-white.png');
            app.OutputButton.Text = 'Output';
            app.StopdateDatePickerLabel_6 = uilabel(app.UIFigure);
            app.StopdateDatePickerLabel_6.Text = 'Repositories';
            app.StopdateDatePickerLabel_7 = uilabel(app.UIFigure);
            app.StopdateDatePickerLabel_7.Text = 'Output files';
            app.LIDARButton = uibutton(app.UIFigure, 'push');
            app.LIDARButton.ButtonPushedFcn = createCallbackFcn(app, @LIDARButtonPushed, true);
            app.LIDARButton.Icon = fullfile(pathToMLAPP, 'Interface', 'icon-folder-white.png');
            app.LIDARButton.Text = 'LIDAR';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AuthenticationTab = uitab(app.TabGroup);
            app.AuthenticationTab.Title = 'Authentication';
            app.GenericSCCAuthenticationLabel = uilabel(app.AuthenticationTab);
            app.GenericSCCAuthenticationLabel.Text = '1. Generic SCC Authentication ';
            app.LoginButton = uibutton(app.AuthenticationTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.PersonalSCCAuthenticationLabel = uilabel(app.AuthenticationTab);
            app.PersonalSCCAuthenticationLabel.Text = '2. Personal SCC Authentication ';
            app.Image = uiimage(app.AuthenticationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Interface', 'logo-data-crossing.png');
            app.Label_2 = uilabel(app.AuthenticationTab);
            app.Label_2.Text = '2024 DataCrossing v1.0';
            app.SCCuserEditFieldLabel = uilabel(app.AuthenticationTab);
            app.SCCuserEditFieldLabel.Text = 'SCC user';
            app.SCCuserEditField = uieditfield(app.AuthenticationTab, 'text');
            app.SCCpasswordEditFieldLabel = uilabel(app.AuthenticationTab);
            app.SCCpasswordEditFieldLabel.Text = 'SCC password';
            app.SCCpasswordEditField = uieditfield(app.AuthenticationTab, 'text');
            app.SCCpasswordEditField.ValueChangingFcn = createCallbackFcn(app, @SCCpasswordEditFieldValueChanging, true);
            app.UsernameEditFieldLabel = uilabel(app.AuthenticationTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.AuthenticationTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.AuthenticationTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.AuthenticationTab, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.StatusLampLabel = uilabel(app.AuthenticationTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.AuthenticationTab);
            app.PreBuildingTab = uitab(app.TabGroup);
            app.PreBuildingTab.Title = 'PreBuilding';
            app.LoadButton = uibutton(app.PreBuildingTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LidardatabaseloadLabel = uilabel(app.PreBuildingTab);
            app.LidardatabaseloadLabel.Text = '1. Lidar database load';
            app.FilterdatesButton = uibutton(app.PreBuildingTab, 'push');
            app.FilterdatesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterdatesButtonPushed, true);
            app.FilterdatesButton.Icon = fullfile(pathToMLAPP, 'Interface', 'icon_filter.png');
            app.FilterdatesButton.Enable = 'off';
            app.FilterdatesButton.Text = 'Filter dates';
            app.SelectedIDLabelDropDown = uidropdown(app.PreBuildingTab);
            app.SelectedIDLabelDropDown.Items = {};
            app.SelectedIDLabelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedIDLabelDropDownValueChanged, true);
            app.SelectedIDLabelDropDown.Enable = 'off';
            app.SelectedIDLabelDropDown.Placeholder = 'Selected ID Label';
            app.SelectedIDLabelDropDown.Value = {};
            app.AcceptButton = uibutton(app.PreBuildingTab, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Enable = 'off';
            app.AcceptButton.Text = 'Accept';
            app.FilterbydatesLabel = uilabel(app.PreBuildingTab);
            app.FilterbydatesLabel.Text = '2. Filter by dates';
            app.LidardatacollectionLabel = uilabel(app.PreBuildingTab);
            app.LidardatacollectionLabel.Text = '3. Lidar data collection';
            app.StopdateDatePickerLabel_2 = uilabel(app.PreBuildingTab);
            app.StopdateDatePickerLabel_2.Text = 'Select a local database';
            app.SettingsButtonGroup = uibuttongroup(app.PreBuildingTab);
            app.SettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SettingsButtonGroupSelectionChanged, true);
            app.SettingsButtonGroup.Title = 'Settings';
            app.D1_LPhotometerlidarButton = uiradiobutton(app.SettingsButtonGroup);
            app.D1_LPhotometerlidarButton.Enable = 'off';
            app.D1_LPhotometerlidarButton.Text = 'D1_L: Photometer + lidar';
            app.D1_LPhotometerlidarButton.Value = true;
            app.D1P_LPhotom_pollidarButton = uiradiobutton(app.SettingsButtonGroup);
            app.D1P_LPhotom_pollidarButton.Enable = 'off';
            app.D1P_LPhotom_pollidarButton.Text = 'D1P_L: Photom_pol + lidar';
            app.D1_L_VDPhotometerlidar_depButton = uiradiobutton(app.SettingsButtonGroup);
            app.D1_L_VDPhotometerlidar_depButton.Enable = 'off';
            app.D1_L_VDPhotometerlidar_depButton.Text = 'D1_L_VD: Photometer + lidar_dep';
            app.D1P_L_VDPhotom_pollidar_depButton = uiradiobutton(app.SettingsButtonGroup);
            app.D1P_L_VDPhotom_pollidar_depButton.Enable = 'off';
            app.D1P_L_VDPhotom_pollidar_depButton.Text = 'D1P_L_VD: Photom_pol + lidar_dep';
            app.SendButton = uibutton(app.PreBuildingTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.GRASPfilebuilderLabel = uilabel(app.PreBuildingTab);
            app.GRASPfilebuilderLabel.Text = '4. GRASP file builder';
            app.PlotdataButton = uibutton(app.PreBuildingTab, 'push');
            app.PlotdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.PlotdataButton.Icon = fullfile(pathToMLAPP, 'Interface', 'icon-graph.png');
            app.PlotdataButton.Enable = 'off';
            app.PlotdataButton.Text = 'Plot data';
            app.StopdateDatePickerLabel_5 = uilabel(app.PreBuildingTab);
            app.StopdateDatePickerLabel_5.Text = 'Select your lidar measure ID';
            app.ViewfiguresCheckBox = uicheckbox(app.PreBuildingTab);
            app.ViewfiguresCheckBox.Text = 'View figures';
            app.RButton = uibutton(app.PreBuildingTab, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Enable = 'off';
            app.RButton.Text = 'R';
            app.RButton_2 = uibutton(app.PreBuildingTab, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Enable = 'off';
            app.RButton_2.Text = 'R';
            app.ExportLIDARCheckBox = uicheckbox(app.PreBuildingTab);
            app.ExportLIDARCheckBox.Visible = 'off';
            app.ExportLIDARCheckBox.Text = 'Export LIDAR';
            app.AltiudeSwitch = uiswitch(app.PreBuildingTab, 'slider');
            app.AltiudeSwitch.Items = {'ELPP', 'VD'};
            app.AltiudeSwitch.ValueChangedFcn = createCallbackFcn(app, @AltiudeSwitchValueChanged, true);
            app.AltiudeSwitch.Enable = 'off';
            app.AltiudeSwitch.Value = 'ELPP';
            app.StartDateDatePickerLabel = uilabel(app.PreBuildingTab);
            app.StartDateDatePickerLabel.Enable = 'off';
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.PreBuildingTab);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDateDatePicker.Enable = 'off';
            app.StartDateDatePicker.Value = datetime([2022 10 1]);
            app.StopdateDatePickerLabel = uilabel(app.PreBuildingTab);
            app.StopdateDatePickerLabel.Enable = 'off';
            app.StopdateDatePickerLabel.Text = 'Stop date';
            app.StopdateDatePicker = uidatepicker(app.PreBuildingTab);
            app.StopdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StopdateDatePicker.Enable = 'off';
            app.StopdateDatePicker.Value = datetime([2022 10 1]);
            app.HeightminSpinnerLabel = uilabel(app.PreBuildingTab);
            app.HeightminSpinnerLabel.Text = 'Height min.';
            app.HeightminSpinner = uispinner(app.PreBuildingTab);
            app.HeightminSpinner.Limits = [100 1000];
            app.HeightminSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightminSpinnerValueChanged, true);
            app.HeightminSpinner.Enable = 'off';
            app.HeightminSpinner.Value = 100;
            app.HeightmaxSpinnerLabel = uilabel(app.PreBuildingTab);
            app.HeightmaxSpinnerLabel.Text = 'Height max.';
            app.HeightmaxSpinner = uispinner(app.PreBuildingTab);
            app.HeightmaxSpinner.Limits = [1000 50000];
            app.HeightmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightmaxSpinnerValueChanged, true);
            app.HeightmaxSpinner.Enable = 'off';
            app.HeightmaxSpinner.Value = 5000;
            app.CurrentIDEditFieldLabel = uilabel(app.PreBuildingTab);
            app.CurrentIDEditFieldLabel.Text = 'Current ID:';
            app.CurrentIDEditField = uieditfield(app.PreBuildingTab, 'text');
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.SavefiguresButton = uibutton(app.PlotTab, 'push');
            app.SavefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiguresButtonPushed, true);
            app.SavefiguresButton.Text = 'Save figures';
            app.Button_2 = uibutton(app.PlotTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = 'buttonReload';
            app.Button_2.Icon = fullfile(pathToMLAPP, 'Interface', 'icon_reset.png');
            app.Button_2.Text = '';
            app.SelectalidermeasurementIDDropDownLabel = uilabel(app.PlotTab);
            app.SelectalidermeasurementIDDropDownLabel.Text = '1. Select a lider measurement ID';
            app.SelectalidermeasurementIDDropDown = uidropdown(app.PlotTab);
            app.SelectalidermeasurementIDDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectalidermeasurementIDDropDownOpening, true);
            app.SelectalidermeasurementIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectalidermeasurementIDDropDownValueChanged, true);
            app.SelectameasurementfiletoshowDropDown_2Label = uilabel(app.PlotTab);
            app.SelectameasurementfiletoshowDropDown_2Label.Text = '2. Select a measurement file to show';
            app.SelectameasurementfiletoshowDropDown_2 = uidropdown(app.PlotTab);
            app.SelectameasurementfiletoshowDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @SelectameasurementfiletoshowDropDown_2Opening, true);
            app.SelectameasurementfiletoshowDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectameasurementfiletoshowDropDown_2ValueChanged, true);
            app.Label_4 = uilabel(app.PlotTab);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.PlotTab);
            app.Label_3.Text = 'Use the broom to clear the visor. If any figure is available to show, check your Measure ID and configurations and save figures first.';
            app.SelectthefiguretoshowLabel = uilabel(app.PlotTab);
            app.SelectthefiguretoshowLabel.Text = '3. Select a figure to show';
            app.SelectafiguretoshowDropDown = uidropdown(app.PlotTab);
            app.PlotfigureButton = uibutton(app.PlotTab, 'push');
            app.PlotfigureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfigureButtonPushed, true);
            app.PlotfigureButton.Icon = fullfile(pathToMLAPP, 'Interface', 'icon-graph.png');
            app.PlotfigureButton.Enable = 'off';
            app.PlotfigureButton.Text = 'Plot figure';
            app.Button = uibutton(app.PlotTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'buttonReload';
            app.Button.Icon = fullfile(pathToMLAPP, 'Interface', 'icon_broom.png');
            app.Button.Text = '';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HTML = uihtml(app.HelpTab);
            app.HTML.HTMLSource = './documentation/docs/functions/AuthSCCAccess_doc.html';
            app.Tree = uitree(app.HelpTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UserGuidesNode = uitreenode(app.Tree);
            app.UserGuidesNode.Icon = fullfile(pathToMLAPP, 'Interface', 'folder.png');
            app.UserGuidesNode.Text = 'User Guides';
            app.FunctionsNode = uitreenode(app.Tree);
            app.FunctionsNode.Icon = fullfile(pathToMLAPP, 'Interface', 'icon-folder-white.png');
            app.FunctionsNode.Text = 'Functions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataCrossing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end